classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        LoadWedgesFileMenu       matlab.ui.container.Menu
        LoadConstraintsFileMenu  matlab.ui.container.Menu
        LoadStrategyFileMenu     matlab.ui.container.Menu
        Menu2                    matlab.ui.container.Menu
        UIAxes                   matlab.ui.control.UIAxes
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        Tab2                     matlab.ui.container.Tab
    end


    properties (Access = private)
        WedgesData      % This property will hold processed data.
        ConstraintsData % This property will hold processed data.
        StrategyData    % This property will hold processed data.
    end

    methods (Access = private)
    
        function WedgesData = readWedges(app, fileID)
            % This fucntion's purpose is to read in and process
            % the wedges file and outputs the data as a matrix.
            % In the matrix M(1,1) would correspond to the label of the first wedge
            % M(2,2) would correspind to the value of the second wedge. Etc.
            
            %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~
             
        end
        
        
        function ConstraintsData = readConstraints(app, fileID)
            %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~
        end
        
        
        function StrategyData = readStrategy(app, fileID)
            %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~  
        end
    end

    methods (Access = private)

        % Callback function
        function LoadWedgesButtonPushed(app, event)
            % This callback acts when a button is pressed,
            % all it does is open the the file using fopen(),
            % then it will provide feedback via the specified lamp.
            
            
            fileName = uigetfile;
            
            try % Try to open the file
                
                fileID = fopen(fileName);
                % Check if fileName is a valid address.
                if fileID == -1
                    % Change the lamp color to red.
                    app.LampWedges.Color = [1 0 0];
                else 
%                     climatewedges.WedgesData = readWedges(fileID);
                    % Change the lamp color to green.
                    app.LampWedges.Color = [0 1 0];
                end
                
            catch % The file could not be opened
                
                app.LampWedges.Color = [1 0 0];
                disp('Please enter a valid file address.');
                
            end
        end

        % Callback function
        function LoadConstraintsButtonPushed(app, event)
            % This callback acts when a button is pressed,
            % all it does is open the the file using fopen(),
            % then it will provide feedback via the specified lamp.
            
            
            fileName = app.ConstraintsEditField.Value;
            try
                fileID = fopen(fileName);
                if fileID == -1
                    app.LampConstraints.Color = [1 0 0];
                else  
%                     climatewedges.ConstraintsData = readConstraints(fileID);
                    app.LampConstraints.Color = [0 1 0];
                end
            catch
                app.LampConstraints.Color = [1 0 0];
                disp('Please enter a valid file address.');
            end
        end

        % Callback function
        function LoadStrategyButtonPushed(app, event)
            % This callback acts when a button is pressed,
            % all it does is open the the file using fopen(),
            % then it will provide feedback via the specified lamp.
            
            
            fileName = app.StrategyEditField.Value;
            try
                fileID = fopen(fileName);
                if fileID == -1
                    app.LampStrategy.Color = [1 0 0];
                else
%                     climatewedges.StrategyData = readStrategy(fileID);
                    app.LampStrategy.Color = [0 1 0];
                end
            catch
                app.LampStrategy.Color = [1 0 0];
                disp('Please enter a valid file address.');
            end
        end

        % Menu selected function: LoadWedgesFileMenu
        function LoadWedgesFileMenuSelected(app, event)
            fileName = uigetfile
            fileID = fopen(fileName);
            
            %climatewedge.StrategyData = readStrategy(fileID);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadWedgesFileMenu = uimenu(app.FileMenu);
            app.LoadWedgesFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadWedgesFileMenuSelected, true);
            app.LoadWedgesFileMenu.Text = 'Load Wedges File';
            app.LoadConstraintsFileMenu = uimenu(app.FileMenu);
            app.LoadConstraintsFileMenu.Text = 'Load Constraints File';
            app.LoadStrategyFileMenu = uimenu(app.FileMenu);
            app.LoadStrategyFileMenu.Text = 'Load Strategy File';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end